classdef VisurStend < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        mainPanel             matlab.ui.container.Panel
        Panel                 matlab.ui.container.Panel
        UpdateButton          matlab.ui.control.Button
        DropDown              matlab.ui.control.DropDown
        ConnectButton         matlab.ui.control.Button
        Lamp                  matlab.ui.control.Lamp
        Panel_2               matlab.ui.container.Panel
        Button                matlab.ui.control.Button
        NAMURButton           matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        relayPanel            matlab.ui.container.Panel
        Sensor1Panel          matlab.ui.container.Panel
        OnOffButton           matlab.ui.control.StateButton
        KnobLabel             matlab.ui.control.Label
        Knob                  matlab.ui.control.DiscreteKnob
        Label_3               matlab.ui.control.Label
        Switch                matlab.ui.control.Switch
        Panel_4               matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        mAEditFieldLabel      matlab.ui.control.Label
        mAEditField           matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        Lamp_2                matlab.ui.control.Lamp
        Label_2               matlab.ui.control.Label
        Lamp_3                matlab.ui.control.Lamp
        Label_4               matlab.ui.control.Label
        EditField             matlab.ui.control.NumericEditField
        Sensor2Panel          matlab.ui.container.Panel
        OnOffButton_2         matlab.ui.control.StateButton
        Knob_2Label           matlab.ui.control.Label
        Knob_2                matlab.ui.control.DiscreteKnob
        Switch_2Label         matlab.ui.control.Label
        Switch_2              matlab.ui.control.Switch
        Panel_5               matlab.ui.container.Panel
        UIAxes_2              matlab.ui.control.UIAxes
        mAEditField_2Label    matlab.ui.control.Label
        mAEditField_2         matlab.ui.control.NumericEditField
        Lamp_4Label           matlab.ui.control.Label
        Lamp_4                matlab.ui.control.Lamp
        Lamp_5Label           matlab.ui.control.Label
        Lamp_5                matlab.ui.control.Lamp
        EditField_2Label      matlab.ui.control.Label
        EditField_2           matlab.ui.control.NumericEditField
        Sensor3Panel          matlab.ui.container.Panel
        Panel_6               matlab.ui.container.Panel
        UIAxes_3              matlab.ui.control.UIAxes
        mAEditField_3Label    matlab.ui.control.Label
        mAEditField_3         matlab.ui.control.NumericEditField
        Lamp_6Label           matlab.ui.control.Label
        Lamp_6                matlab.ui.control.Lamp
        Lamp_7Label           matlab.ui.control.Label
        Lamp_7                matlab.ui.control.Lamp
        OnOffButton_3         matlab.ui.control.StateButton
        Knob_3Label           matlab.ui.control.Label
        Knob_3                matlab.ui.control.DiscreteKnob
        Switch_3Label         matlab.ui.control.Label
        Switch_3              matlab.ui.control.Switch
        EditField_3Label      matlab.ui.control.Label
        EditField_3           matlab.ui.control.NumericEditField
        XButton_relay         matlab.ui.control.Button
        Button_4              matlab.ui.control.StateButton
        Button_3              matlab.ui.control.Button
        RxIndicator           matlab.ui.control.Lamp
        NamurPanel            matlab.ui.container.Panel
        Sensor1Panel_2        matlab.ui.container.Panel
        OnOffButton1_Namur    matlab.ui.control.StateButton
        Knob_4Label           matlab.ui.control.Label
        Knob_4                matlab.ui.control.DiscreteKnob
        Panel_7               matlab.ui.container.Panel
        UIAxes_4              matlab.ui.control.UIAxes
        mAEditField_4Label    matlab.ui.control.Label
        mAEditField_4         matlab.ui.control.NumericEditField
        Lamp_8Label           matlab.ui.control.Label
        Lamp_8                matlab.ui.control.Lamp
        Lamp_9Label           matlab.ui.control.Label
        Lamp_9                matlab.ui.control.Lamp
        EditField_4Label      matlab.ui.control.Label
        EditField_4           matlab.ui.control.NumericEditField
        XButton_Namur         matlab.ui.control.Button
        Test_Namur            matlab.ui.control.StateButton
        Button_6              matlab.ui.control.Button
        RxIndicator_2         matlab.ui.control.Lamp
        Sensor2Panel_2        matlab.ui.container.Panel
        OnOffButton2_Namur    matlab.ui.control.StateButton
        Knob_5Label           matlab.ui.control.Label
        Knob_5                matlab.ui.control.DiscreteKnob
        Panel_8               matlab.ui.container.Panel
        UIAxes_5              matlab.ui.control.UIAxes
        mAEditField_5Label    matlab.ui.control.Label
        mAEditField_5         matlab.ui.control.NumericEditField
        Lamp_10Label          matlab.ui.control.Label
        Lamp_10               matlab.ui.control.Lamp
        Lamp_11Label          matlab.ui.control.Label
        Lamp_11               matlab.ui.control.Lamp
        EditField_5Label      matlab.ui.control.Label
        EditField_5           matlab.ui.control.NumericEditField
        Sensor3Panel_2        matlab.ui.container.Panel
        Panel_9               matlab.ui.container.Panel
        UIAxes_6              matlab.ui.control.UIAxes
        mAEditField_6Label    matlab.ui.control.Label
        mAEditField_6         matlab.ui.control.NumericEditField
        Lamp_12Label          matlab.ui.control.Label
        Lamp_12               matlab.ui.control.Lamp
        Lamp_13Label          matlab.ui.control.Label
        Lamp_13               matlab.ui.control.Lamp
        OnOffButton3_Namur    matlab.ui.control.StateButton
        Knob_6Label           matlab.ui.control.Label
        Knob_6                matlab.ui.control.DiscreteKnob
        EditField_6Label      matlab.ui.control.Label
        EditField_6           matlab.ui.control.NumericEditField
        CurrentPanel          matlab.ui.container.Panel
        XButton_Namur_2       matlab.ui.control.Button
        Test_Current          matlab.ui.control.StateButton
        Button_7              matlab.ui.control.Button
        RxIndicator_3         matlab.ui.control.Lamp
        Sensor1Panel_3        matlab.ui.container.Panel
        OnOffButton1_Current  matlab.ui.control.StateButton
        Knob_7Label           matlab.ui.control.Label
        Knob_7                matlab.ui.control.DiscreteKnob
        Panel_10              matlab.ui.container.Panel
        UIAxes_7              matlab.ui.control.UIAxes
        mAEditField_7Label    matlab.ui.control.Label
        mAEditField_7         matlab.ui.control.NumericEditField
        Lamp_14Label          matlab.ui.control.Label
        Lamp_14               matlab.ui.control.Lamp
        Lamp_15Label          matlab.ui.control.Label
        Lamp_15               matlab.ui.control.Lamp
        EditField_7Label      matlab.ui.control.Label
        EditField_7           matlab.ui.control.NumericEditField
        Sensor2Panel_3        matlab.ui.container.Panel
        OnOffButton2_Current  matlab.ui.control.StateButton
        Knob_8Label           matlab.ui.control.Label
        Knob_8                matlab.ui.control.DiscreteKnob
        Panel_11              matlab.ui.container.Panel
        UIAxes_8              matlab.ui.control.UIAxes
        mAEditField_8Label    matlab.ui.control.Label
        mAEditField_8         matlab.ui.control.NumericEditField
        Lamp_16Label          matlab.ui.control.Label
        Lamp_16               matlab.ui.control.Lamp
        Lamp_17Label          matlab.ui.control.Label
        Lamp_17               matlab.ui.control.Lamp
        EditField_8Label      matlab.ui.control.Label
        EditField_8           matlab.ui.control.NumericEditField
        Sensor3Panel_3        matlab.ui.container.Panel
        Panel_12              matlab.ui.container.Panel
        UIAxes_9              matlab.ui.control.UIAxes
        mAEditField_9Label    matlab.ui.control.Label
        mAEditField_9         matlab.ui.control.NumericEditField
        Lamp_18Label          matlab.ui.control.Label
        Lamp_18               matlab.ui.control.Lamp
        Lamp_19Label          matlab.ui.control.Label
        Lamp_19               matlab.ui.control.Lamp
        OnOffButton3_Current  matlab.ui.control.StateButton
        Knob_9Label           matlab.ui.control.Label
        Knob_9                matlab.ui.control.DiscreteKnob
        EditField_9Label      matlab.ui.control.Label
        EditField_9           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        WorkComPort=0; 
        SendCommand % Description
        ConToCom=0 % Description
        Sensor1Stat=0 % Description
        Sensor2Stat=0 % Description
        Sensor3Stat=0 % Description
        Command % Description
        Mode=0 % Description
        NumCommand=0 % Description
        RxDataTimer % Description
        LevelPower=0x31; % Description
        OnTest=0 % флаг тесирования
    end

    
    methods (Access = private)
        
        function SendCommandFcn(app,~,~)
            
            switch app.NumCommand
               
                case 1
                s = serialport(app.WorkComPort,9600);
             
                app.MessageCreation;
            
                write(s,app.Command,"uint8");
                start(app.RxDataTimer);
                stop(app.SendCommand);    
                
                
                case 2
                s = serialport(app.WorkComPort,9600);
             
                app.MessageCreation;
            
                write(s,app.Command,"uint8");
                %start(app.RxDataTimer);
                %stop(app.SendCommand);    
                
                case 3
                s = serialport(app.WorkComPort,9600);
             
                app.MessageCreation;
            
                write(s,app.Command,"uint8");
                %start(app.RxDataTimer);
                stop(app.SendCommand); 


            end


        end
        
        
         function ReadCommandFcn(app,~,~)

            s = serialport(app.WorkComPort,9600);
            
            RXdata = read(s,6,"uint8");            
                          
            switch RXdata(2)
                
                case 0x01
                    
                  stop(app.RxDataTimer);
                 
                
                case 0x02
                    
                  app.RxIndicator.Color=[0.39,0.83,0.07];
                 % app.NumCommand=3; %готовность к отправке третьей команды  
                  stop(app.RxDataTimer);
                  start(app.SendCommand);
            end


        end
        
        
        function MessageCreation(app)
            
            app.Command=zeros(1,6);
            
            switch app.NumCommand
                
                case 1  %запрос на подключение к стенду
            app.Command(1)=0xAA;
            app.Command(2)=app.NumCommand;
            app.Command(3)=0xAA;
            app.Command(4)=0xAA;
            app.Command(5)=0xAA;
            app.Command(6)=0xAA;    
            
                case 2  %первоначальная инициализация  
            app.Command(1)=0xAA;
            app.Command(2)=app.NumCommand;
            app.Command(3)=app.Mode;
            app.Command(4)=app.Sensor1Stat;
            app.Command(5)=app.Sensor2Stat;
            app.Command(6)=app.Sensor3Stat;
            
                case 3 %уровень напряжения 
            app.Command(1)=0xAA;
            app.Command(2)=app.NumCommand;
            app.Command(3)=app.LevelPower;
            app.Command(4)=app.Sensor1Stat;
            app.Command(5)=app.Sensor2Stat;
            app.Command(6)=app.Sensor3Stat;
                    
           end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %Preview
            app.Lamp_2.Enable=0;
            app.Lamp_3.Enable=0;
            app.Lamp_4.Enable=0;
            app.Lamp_5.Enable=0;
            app.Lamp_6.Enable=0;
            app.Lamp_7.Enable=0;
            
            app.Lamp_8.Enable=0;
            app.Lamp_9.Enable=0;
            app.Lamp_10.Enable=0;
            app.Lamp_11.Enable=0;
            app.Lamp_12.Enable=0;
            app.Lamp_13.Enable=0;
            
            app.Lamp_14.Enable=0;
            app.Lamp_15.Enable=0;
            app.Lamp_16.Enable=0;
            app.Lamp_17.Enable=0;
            app.Lamp_18.Enable=0;
            app.Lamp_19.Enable=0;


            %Free COM port's
            app.DropDown.Items = serialportlist("available");
            
             % Create timer object
             
            app.SendCommand = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.SendCommandFcn);        % Specify callback function
            
            app.RxDataTimer= timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.ReadCommandFcn);    % Specify callback function


        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            app.DropDown.Items = serialportlist("available");
            

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            if app.ConnectButton.Text == 'Disconn'
               app.ConnectButton.Text = 'Connect';
               app.ConToCom=0;
               app.Lamp.Color=[0.80,0.80,0.80];
            end
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            if  app.ConnectButton.Text == 'Connect'
            s = app.DropDown.Value;
            app.NumCommand=1;
            app.MessageCreation;
            app.WorkComPort=s;
            start(app.SendCommand);
            
             %serialport(app.WorkComPort,9600);
            app.ConnectButton.Text = 'Disconn';
            app.ConToCom=1;
            app.Lamp.Color=[0.39,0.83,0.07];  
            
            
            %Посылка запроса стенду
%             app.NumCommand=1;
%             app.MessageCreation;
%             app.WorkComPort=s;
%             start(app.SendCommand);


            else
            clear app.WorkComPort;    
            app.ConnectButton.Text = 'Connect';
            app.ConToCom=0;
            app.Lamp.Color=[0.80,0.80,0.80];
            
            end
            
             
        end

        % Callback function
        function ButtonPushed(app, event)
            fclose("all");
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
         if app.ConToCom==1
                app.relayPanel.Visible=1;
                app.Mode=0x0A;
                app.mainPanel.Visible=0;  
         end
              
        end

        % Button pushed function: XButton_relay
        function XButton_relayPushed(app, event)
            stop(app.SendCommand);
            stop(app.RxDataTimer);
            app.OnTest=0;
            
            app.Sensor1Stat=0;
            app.Sensor2Stat=0;
            app.Sensor3Stat=0;
            
            app.Button_4.Value=0;
            app.Button_4.BackgroundColor=[0.90,0.90,0.90];
            
            app.NumCommand=1; %возврат к первой команде
            
            app.relayPanel.Visible=0;
            app.OnOffButton.Enable=1;
            app.OnOffButton_2.Enable=1;
            app.OnOffButton_3.Enable=1;
            app.mainPanel.Visible=1;
        end

        % Value changed function: OnOffButton
        function OnOffButtonValueChanged(app, event)
            value = app.OnOffButton.Value;
            app.Sensor1Stat=value;
            
            %Power tumbler
            
            if (app.OnOffButton.Value==1) 
               app.Knob.Visible=1;
               app.KnobLabel.Visible=1;
               app.Knob_2.Visible=0;
               app.Knob_2Label.Visible=0;
               app.Knob_3.Visible=0;
               app.Knob_3Label.Visible=0;
            elseif (app.OnOffButton.Value==0 && app.OnOffButton_2.Value==1)
               app.Knob.Visible=0;
               app.KnobLabel.Visible=0;
               app.Knob_2.Visible=1;
               app.Knob_2Label.Visible=1;
               app.Knob_3.Visible=0;
               app.Knob_3Label.Visible=0;
               elseif (app.OnOffButton_2.Value==0 && app.OnOffButton_3.Value==1 && app.OnOffButton.Value==0)
               app.Knob.Visible=0;
               app.KnobLabel.Visible=0;
               app.Knob_2.Visible=0;
               app.Knob_2Label.Visible=0;
               app.Knob_3.Visible=1;
               app.Knob_3Label.Visible=1;
            else
               app.Knob.Visible=0;
               app.KnobLabel.Visible=0;
            end


            if value==1
                app.OnOffButton.BackgroundColor=[0.42,0.69,0.66];
                app.mAEditField.Enable=1;
                app.mAEditFieldLabel.Enable=1;
                app.Lamp_2.Enable=1;
                app.Lamp_3.Enable=1;
            else
                app.OnOffButton.BackgroundColor=[0.90,0.90,0.90];
                app.mAEditField.Enable=0;
                app.mAEditFieldLabel.Enable=0;
                app.Lamp_2.Enable=0;
                app.Lamp_3.Enable=0;
            end
        end

        % Value changed function: OnOffButton_2
        function OnOffButton_2ValueChanged(app, event)
            value = app.OnOffButton_2.Value;
            app.Sensor2Stat=value;
            %Power tumbler
            
            if (app.OnOffButton.Value==0 && app.OnOffButton_2.Value==1) 
               app.Knob_2.Visible=1;
               app.Knob_2Label.Visible=1;
               app.Knob_3.Visible=0;
               app.Knob_3Label.Visible=0;
            elseif (app.OnOffButton_2.Value==0 && app.OnOffButton_3.Value==1 && app.OnOffButton.Value==0)
               app.Knob_2.Visible=0;
               app.Knob_2Label.Visible=0;
               app.Knob_3.Visible=1;
               app.Knob_3Label.Visible=1;
               
            else
               app.Knob_2.Visible=0;
               app.Knob_2Label.Visible=0;
            end
            

           %Output data vision 
            if value==1
                app.OnOffButton_2.BackgroundColor=[0.42,0.69,0.66];
                app.mAEditField_2.Enable=1;
                app.mAEditField_2Label.Enable=1;
                app.Lamp_4.Enable=1;
                app.Lamp_5.Enable=1;
            else
                app.OnOffButton_2.BackgroundColor=[0.90,0.90,0.90];
                app.mAEditField_2.Enable=0;
                app.mAEditField_2Label.Enable=0;
                app.Lamp_4.Enable=0;
                app.Lamp_5.Enable=0;
            end
        end

        % Value changed function: OnOffButton_3
        function OnOffButton_3ValueChanged(app, event)
            value = app.OnOffButton_3.Value;
            app.Sensor3Stat=value;
             %Power tumbler
            
            if (app.OnOffButton.Value==0 && app.OnOffButton_2.Value==0 && app.OnOffButton_3.Value==1) 
               app.Knob_3.Visible=1;
               app.Knob_3Label.Visible=1;
            else
               app.Knob_3.Visible=0;
               app.Knob_3Label.Visible=0;
            end
            
            if value==1
                app.OnOffButton_3.BackgroundColor=[0.42,0.69,0.66];
                app.mAEditField_3.Enable=1;
                app.mAEditField_3Label.Enable=1;
                app.Lamp_6.Enable=1;
                app.Lamp_7.Enable=1;
            else
                app.OnOffButton_3.BackgroundColor=[0.90,0.90,0.90];
                app.mAEditField_3.Enable=0;
                app.mAEditField_3Label.Enable=0;
                app.Lamp_6.Enable=0;
                app.Lamp_7.Enable=0;
            end
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
        
            value=app.Button_4.Value;
            
            if(app.Sensor1Stat==0 && app.Sensor2Stat==0 && app.Sensor3Stat==0)
                
            else    
                %Включение таймера(отправка команды по UART)
                if value==1
                    
                    %strcmp(app.SendCommand.Running, 'off')
                    
                    app.NumCommand=2;
                    
                    app.Button_4.BackgroundColor=[0.42,0.69,0.66];
                    app.OnOffButton.Enable=0;
                    app.OnOffButton_2.Enable=0;
                    app.OnOffButton_3.Enable=0;
                    
                    start(app.SendCommand);
                    app.OnTest=1;

                else 
                    stop(app.SendCommand);
                    stop(app.RxDataTimer);
                    
                    app.Button_4.BackgroundColor=[0.96,0.96,0.96];
                    app.OnOffButton.Enable=1;
                    app.OnOffButton_2.Enable=1;
                    app.OnOffButton_3.Enable=1;
                    app.OnTest=0;
                    
                    
                end
            end
            

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.SendCommand);
            stop(app.RxDataTimer);
            delete(app.SendCommand);
            delete(app);
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            
           if app.OnTest==1
             stop(app.SendCommand);
           
           
            value = app.Knob.Value;
            app.LevelPower=value;
            app.Knob_2.Value=value;
            app.Knob_3.Value=value;
            
            %передача уровня питания
            app.NumCommand=3;
            start(app.SendCommand);
            
            app.NumCommand=2;
            start(app.SendCommand);
            app.OnTest=1;
            
            end
           
            if app.OnTest==0

            value = app.Knob.Value;
            app.LevelPower=value;
            app.Knob_2.Value=value;
            app.Knob_3.Value=value;
            
            %передача уровня питания
            app.Command(1)=0xAA;
            app.Command(2)=0x03;
            app.Command(3)=app.LevelPower;
            app.Command(4)=app.Sensor1Stat;
            app.Command(5)=app.Sensor2Stat;
            app.Command(6)=app.Sensor3Stat;  
            
            s = serialport(app.WorkComPort,9600);
            write(s,app.Command,"uint8");
            
            end


        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
%             value = app.Knob_2.Value;
%             app.LevelPower=value;
%             app.Knob.Value=value;
%             app.Knob_3.Value=value;
            
            %%%%%%%%%%%%%%%%%%%%%%%
            
             if app.OnTest==1
             stop(app.SendCommand);
           
           
            value = app.Knob_2.Value;
            app.LevelPower=value;
            app.Knob.Value=value;
            app.Knob_3.Value=value;
            
            %передача уровня питания
            app.NumCommand=3;
            start(app.SendCommand);
            
            app.NumCommand=2;
            start(app.SendCommand);
            app.OnTest=1;
            
            end
           
            if app.OnTest==0

            value = app.Knob_2.Value;
            app.LevelPower=value;
            app.Knob.Value=value;
            app.Knob_3.Value=value;
            
            %передача уровня питания
            app.Command(1)=0xAA;
            app.Command(2)=0x03;
            app.Command(3)=app.LevelPower;
            app.Command(4)=app.Sensor1Stat;
            app.Command(5)=app.Sensor2Stat;
            app.Command(6)=app.Sensor3Stat;  
            
            s = serialport(app.WorkComPort,9600);
            write(s,app.Command,"uint8");
            
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%


        end

        % Value changed function: Knob_3
        function Knob_3ValueChanged(app, event)
%             value = app.Knob_3.Value;
%             app.LevelPower=value;
%             app.Knob.Value=value;
%             app.Knob_2.Value=value;
            
            
            %%%%%%%%%%%%%%%
            
             if app.OnTest==1
             stop(app.SendCommand);
           
           
            value = app.Knob_3.Value;
            app.LevelPower=value;
            app.Knob.Value=value;
            app.Knob_2.Value=value;
            
            %передача уровня питания
            app.NumCommand=3;
            start(app.SendCommand);
            
            app.NumCommand=2;
            start(app.SendCommand);
            app.OnTest=1;
            
            end
           
            if app.OnTest==0

            value = app.Knob_3.Value;
            app.LevelPower=value;
            app.Knob.Value=value;
            app.Knob_2.Value=value;
            
            %передача уровня питания
            app.Command(1)=0xAA;
            app.Command(2)=0x03;
            app.Command(3)=app.LevelPower;
            app.Command(4)=app.Sensor1Stat;
            app.Command(5)=app.Sensor2Stat;
            app.Command(6)=app.Sensor3Stat;  
            
            s = serialport(app.WorkComPort,9600);
            write(s,app.Command,"uint8");
            
            end
            
            %%%%%%%%%%%%%%%


        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
f=fopen('my.txt','wt');

SensorNum=app.EditField.Value;
SensorNum = num2str(SensorNum);

%Генерация отчета

%fprintf(f,'Номер датчика: ');
fprintf(f,'Номер датчика: %s\n',SensorNum);

fprintf(f,' еще текст\n');

fprintf(f,'текст с новой строки');

fclose(f);
        end

        % Callback function
        function TxButtonPushed(app, event)
            s = serialport(app.WorkComPort,9600);
            message=[0xAA,0x01,0x02,0x03,0x04];
            write(s,message,"uint8");
            clear s;
        end

        % Callback function
        function RxButtonPushed(app, event)
            s = serialport(app.WorkComPort,9600);
            
            %s = serialport("COM5",9600);
            
            RXdata = read(s,5,"uint8");
            
            MyText=num2str(RXdata);
            app.RxMonitor.Value=MyText;
             
            clear s;
            
            
            %app.RxMonitor.Value=data;
        end

        % Callback function
        function Button_5Pushed(app, event)
            
            
            valueh=[0xAA,0xBB,0xCC];


            MyText=num2str(valueh);
            app.RxMonitor.Value=MyText;


        end

        % Button pushed function: NAMURButton
        function NAMURButtonPushed(app, event)
             if app.ConToCom==1
                app.NamurPanel.Visible=1;
                app.Mode=0x0B;
                app.mainPanel.Visible=0;  
             end
        end

        % Button pushed function: XButton_Namur
        function XButton_NamurPushed(app, event)
            stop(app.SendCommand);
            stop(app.RxDataTimer);
            app.OnTest=0;
            
            app.Sensor1Stat=0;
            app.Sensor2Stat=0;
            app.Sensor3Stat=0;
            
            app.Test_Namur.Value=0;
            app.Test_Namur.BackgroundColor=[0.90,0.90,0.90];
            
            app.NumCommand=1; %возврат к первой команде
            
            app.NamurPanel.Visible=0;
            app.OnOffButton1_Namur.Enable=1;
            app.OnOffButton2_Namur.Enable=1;
            app.OnOffButton3_Namur.Enable=1;
            app.mainPanel.Visible=1;
        end

        % Value changed function: Test_Namur
        function Test_NamurValueChanged(app, event)
            value = app.Test_Namur.Value;
             
            if(app.Sensor1Stat==0 && app.Sensor2Stat==0 && app.Sensor3Stat==0)
                
            else    
                %Включение таймера(отправка команды по UART)
                if value==1
                    
                    %strcmp(app.SendCommand.Running, 'off')
                    
                    app.NumCommand=2;
                    
                    app.Test_Namur.BackgroundColor=[0.42,0.69,0.66];
                    app.OnOffButton1_Namur.Enable=0;
                    app.OnOffButton2_Namur.Enable=0;
                    app.OnOffButton3_Namur.Enable=0;
                    
                    start(app.SendCommand);
                    app.OnTest=1;

                else 
                    stop(app.SendCommand);
                    stop(app.RxDataTimer);
                    
                    app.Test_Namur.BackgroundColor=[0.96,0.96,0.96];
                    app.OnOffButton1_Namur.Enable=1;
                    app.OnOffButton2_Namur.Enable=1;
                    app.OnOffButton3_Namur.Enable=1;
                    app.OnTest=0;
                    
                end
            end
            
        end

        % Value changed function: OnOffButton1_Namur
        function OnOffButton1_NamurValueChanged(app, event)
            value = app.OnOffButton1_Namur.Value;
            
            app.Sensor1Stat=value;
            
            %Power tumbler
            
            if (app.OnOffButton1_Namur.Value==1) 
               app.Knob_4.Visible=1;
               app.Knob_4Label.Visible=1;
               app.Knob_5.Visible=0;
               app.Knob_5Label.Visible=0;
               app.Knob_6.Visible=0;
               app.Knob_6Label.Visible=0;
            elseif (app.OnOffButton1_Namur.Value==0 && app.OnOffButton2_Namur.Value==1)
               app.Knob_4.Visible=0;
               app.Knob_4Label.Visible=0;
               app.Knob_5.Visible=1;
               app.Knob_5Label.Visible=1;
               app.Knob_6.Visible=0;
               app.Knob_6Label.Visible=0;
            elseif (app.OnOffButton2_Namur.Value==0 && app.OnOffButton3_Namur.Value==1 && app.OnOffButton1_Namur.Value==0)
               app.Knob_4.Visible=0;
               app.Knob_4Label.Visible=0;
               app.Knob_5.Visible=0;
               app.Knob_5Label.Visible=0;
               app.Knob_6.Visible=1;
               app.Knob_6Label.Visible=1;
            else
               app.Knob_4.Visible=0;
               app.Knob_4Label.Visible=0;
               
            end


            if value==1
                app.OnOffButton1_Namur.BackgroundColor=[0.42,0.69,0.66];
                app.mAEditField_4.Enable=1;
                app.mAEditField_4Label.Enable=1;
                app.Lamp_8.Enable=1;
                app.Lamp_9.Enable=1;
            else
                app.OnOffButton1_Namur.BackgroundColor=[0.90,0.90,0.90];
                app.mAEditField_4.Enable=0;
                app.mAEditField_4Label.Enable=0;
                app.Lamp_8.Enable=0;
                app.Lamp_9.Enable=0;
            end


        end

        % Value changed function: Knob_4
        function Knob_4ValueChanged(app, event)
            if app.OnTest==1
             stop(app.SendCommand);
           
           
            value = app.Knob_4.Value;
            app.LevelPower=value;
            app.Knob_5.Value=value;
            app.Knob_6.Value=value;
            
            
            %передача уровня питания
            app.NumCommand=3;
            start(app.SendCommand);
            
            app.NumCommand=2;
            start(app.SendCommand);
            app.OnTest=1;
            
            end
           
            if app.OnTest==0

            value = app.Knob_4.Value;
            app.LevelPower=value;
            app.Knob_5.Value=value;
            app.Knob_6.Value=value;
            
            %передача уровня питания
            app.Command(1)=0xAA;
            app.Command(2)=0x03;
            app.Command(3)=app.LevelPower;
            app.Command(4)=app.Sensor1Stat;
            app.Command(5)=app.Sensor2Stat;
            app.Command(6)=app.Sensor3Stat;  
            
            s = serialport(app.WorkComPort,9600);
            write(s,app.Command,"uint8");
            
            end
        end

        % Value changed function: OnOffButton2_Namur
        function OnOffButton2_NamurValueChanged(app, event)
            value = app.OnOffButton2_Namur.Value;
            app.Sensor2Stat=value;
            %Power tumbler
            
            if (app.OnOffButton1_Namur.Value==0 && app.OnOffButton2_Namur.Value==1) 
               app.Knob_5.Visible=1;
               app.Knob_5Label.Visible=1;
               app.Knob_6.Visible=0;
               app.Knob_6Label.Visible=0;
            elseif (app.OnOffButton2_Namur.Value==0 && app.OnOffButton3_Namur.Value==1 && app.OnOffButton1_Namur.Value==0)
               app.Knob_5.Visible=0;
               app.Knob_5Label.Visible=0;
               app.Knob_6.Visible=1;
               app.Knob_6Label.Visible=1;
               
            else
               app.Knob_5.Visible=0;
               app.Knob_5Label.Visible=0;
            end
            

           %Output data vision 
            if value==1
                app.OnOffButton2_Namur.BackgroundColor=[0.42,0.69,0.66];
                app.mAEditField_5.Enable=1;
                app.mAEditField_5Label.Enable=1;
                app.Lamp_10.Enable=1;
                app.Lamp_11.Enable=1;
            else
                app.OnOffButton2_Namur.BackgroundColor=[0.90,0.90,0.90];
                app.mAEditField_5.Enable=0;
                app.mAEditField_5Label.Enable=0;
                app.Lamp_10.Enable=0;
                app.Lamp_11.Enable=0;
            end
        end

        % Value changed function: OnOffButton3_Namur
        function OnOffButton3_NamurValueChanged(app, event)
            value = app.OnOffButton3_Namur.Value;
            app.Sensor3Stat=value;
             %Power tumbler
            
            if (app.OnOffButton1_Namur.Value==0 && app.OnOffButton2_Namur.Value==0 && app.OnOffButton3_Namur.Value==1) 
               app.Knob_6.Visible=1;
               app.Knob_6Label.Visible=1;
            else
               app.Knob_6.Visible=0;
               app.Knob_6Label.Visible=0;
            end
            
            if value==1
                app.OnOffButton3_Namur.BackgroundColor=[0.42,0.69,0.66];
                app.mAEditField_6.Enable=1;
                app.mAEditField_6Label.Enable=1;
                app.Lamp_12.Enable=1;
                app.Lamp_13.Enable=1;
            else
                app.OnOffButton3_Namur.BackgroundColor=[0.90,0.90,0.90];
                app.mAEditField_6.Enable=0;
                app.mAEditField_6Label.Enable=0;
                app.Lamp_12.Enable=0;
                app.Lamp_13.Enable=0;
            end
        end

        % Value changed function: Knob_5
        function Knob_5ValueChanged(app, event)
            
             %%%%%%%%%%%%%%%%%%%%%%%
            
             if app.OnTest==1
             stop(app.SendCommand);
           
           
            value = app.Knob_5.Value;
            app.LevelPower=value;
            app.Knob_4.Value=value;
            app.Knob_6.Value=value;
            
            %передача уровня питания
            app.NumCommand=3;
            start(app.SendCommand);
            
            app.NumCommand=2;
            start(app.SendCommand);
            app.OnTest=1;
            
            end
           
            if app.OnTest==0

            value = app.Knob_5.Value;
            app.LevelPower=value;
            app.Knob_4.Value=value;
            app.Knob_6.Value=value;
            
            %передача уровня питания
            app.Command(1)=0xAA;
            app.Command(2)=0x03;
            app.Command(3)=app.LevelPower;
            app.Command(4)=app.Sensor1Stat;
            app.Command(5)=app.Sensor2Stat;
            app.Command(6)=app.Sensor3Stat;  
            
            s = serialport(app.WorkComPort,9600);
            write(s,app.Command,"uint8");
            
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%
            
        end

        % Value changed function: Knob_6
        function Knob_6ValueChanged(app, event)
             %%%%%%%%%%%%%%%%%%%%%%%
            
             if app.OnTest==1
             stop(app.SendCommand);
           
           
            value = app.Knob_6.Value;
            app.LevelPower=value;
            app.Knob_4.Value=value;
            app.Knob_5.Value=value;
            
            %передача уровня питания
            app.NumCommand=3;
            start(app.SendCommand);
            
            app.NumCommand=2;
            start(app.SendCommand);
            app.OnTest=1;
            
            end
           
            if app.OnTest==0

            value = app.Knob_6.Value;
            app.LevelPower=value;
            app.Knob_4.Value=value;
            app.Knob_5.Value=value;
            
            %передача уровня питания
            app.Command(1)=0xAA;
            app.Command(2)=0x03;
            app.Command(3)=app.LevelPower;
            app.Command(4)=app.Sensor1Stat;
            app.Command(5)=app.Sensor2Stat;
            app.Command(6)=app.Sensor3Stat;  
            
            s = serialport(app.WorkComPort,9600);
            write(s,app.Command,"uint8");
            
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.ConToCom==1
                app.CurrentPanel.Visible=1;
                app.Mode=0x0C;
                app.mainPanel.Visible=0;  
             end
        end

        % Button pushed function: XButton_Namur_2
        function XButton_Namur_2Pushed(app, event)
            stop(app.SendCommand);
            stop(app.RxDataTimer);
            app.OnTest=0;
            
            app.Sensor1Stat=0;
            app.Sensor2Stat=0;
            app.Sensor3Stat=0;
            
            app.Test_Current.Value=0;
            app.Test_Current.BackgroundColor=[0.90,0.90,0.90];
            
            app.NumCommand=1; %возврат к первой команде
            
            app.CurrentPanel.Visible=0;
            app.OnOffButton1_Current.Enable=1;
            app.OnOffButton2_Current.Enable=1;
            app.OnOffButton3_Current.Enable=1;
            app.mainPanel.Visible=1;
        end

        % Value changed function: OnOffButton1_Current
        function OnOffButton1_CurrentValueChanged(app, event)

            %%%%%
            
            value = app.OnOffButton1_Current.Value;
            
            app.Sensor1Stat=value;
            
            %Power tumbler
            
            if (app.OnOffButton1_Current.Value==1) 
               app.Knob_7.Visible=1;
               app.Knob_7Label.Visible=1;
               app.Knob_8.Visible=0;
               app.Knob_8Label.Visible=0;
               app.Knob_9.Visible=0;
               app.Knob_9Label.Visible=0;
            elseif (app.OnOffButton1_Current.Value==0 && app.OnOffButton2_Current.Value==1)
               app.Knob_7.Visible=0;
               app.Knob_7Label.Visible=0;
               app.Knob_8.Visible=1;
               app.Knob_8Label.Visible=1;
               app.Knob_9.Visible=0;
               app.Knob_9Label.Visible=0;
            elseif (app.OnOffButton2_Current.Value==0 && app.OnOffButton3_Current.Value==1 && app.OnOffButton1_Current.Value==0)
               app.Knob_7.Visible=0;
               app.Knob_7Label.Visible=0;
               app.Knob_8.Visible=0;
               app.Knob_8Label.Visible=0;
               app.Knob_9.Visible=1;
               app.Knob_9Label.Visible=1;
            else
               app.Knob_7.Visible=0;
               app.Knob_7Label.Visible=0;
               
            end


            if value==1
                app.OnOffButton1_Current.BackgroundColor=[0.42,0.69,0.66];
                app.mAEditField_7.Enable=1;
                app.mAEditField_7Label.Enable=1;
                app.Lamp_14.Enable=1;
                app.Lamp_15.Enable=1;
            else
                app.OnOffButton1_Current.BackgroundColor=[0.90,0.90,0.90];
                app.mAEditField_7.Enable=0;
                app.mAEditField_7Label.Enable=0;
                app.Lamp_14.Enable=0;
                app.Lamp_15.Enable=0;
            end
            
            %%%%
            
            
        end

        % Value changed function: OnOffButton2_Current
        function OnOffButton2_CurrentValueChanged(app, event)
            value = app.OnOffButton2_Current.Value;
            
            app.Sensor2Stat=value;
            %Power tumbler
            
            if (app.OnOffButton1_Current.Value==0 && app.OnOffButton2_Current.Value==1) 
               app.Knob_8.Visible=1;
               app.Knob_8Label.Visible=1;
               app.Knob_9.Visible=0;
               app.Knob_9Label.Visible=0;
            elseif (app.OnOffButton2_Current.Value==0 && app.OnOffButton3_Current.Value==1 && app.OnOffButton1_Current.Value==0)
               app.Knob_8.Visible=0;
               app.Knob_8Label.Visible=0;
               app.Knob_9.Visible=1;
               app.Knob_9Label.Visible=1;
               
            else
               app.Knob_8.Visible=0;
               app.Knob_8Label.Visible=0;
            end
            

           %Output data vision 
            if value==1
                app.OnOffButton2_Current.BackgroundColor=[0.42,0.69,0.66];
                app.mAEditField_8.Enable=1;
                app.mAEditField_8Label.Enable=1;
                app.Lamp_16.Enable=1;
                app.Lamp_17.Enable=1;
            else
                app.OnOffButton2_Current.BackgroundColor=[0.90,0.90,0.90];
                app.mAEditField_8.Enable=0;
                app.mAEditField_8Label.Enable=0;
                app.Lamp_16.Enable=0;
                app.Lamp_17.Enable=0;
            end
        end

        % Value changed function: OnOffButton3_Current
        function OnOffButton3_CurrentValueChanged(app, event)
            value = app.OnOffButton3_Current.Value;
            app.Sensor3Stat=value;
             %Power tumbler
            
            if (app.OnOffButton1_Current.Value==0 && app.OnOffButton2_Current.Value==0 && app.OnOffButton3_Current.Value==1) 
               app.Knob_9.Visible=1;
               app.Knob_9Label.Visible=1;
            else
               app.Knob_9.Visible=0;
               app.Knob_9Label.Visible=0;
            end
            
            if value==1
                app.OnOffButton3_Current.BackgroundColor=[0.42,0.69,0.66];
                app.mAEditField_9.Enable=1;
                app.mAEditField_9Label.Enable=1;
                app.Lamp_18.Enable=1;
                app.Lamp_19.Enable=1;
            else
                app.OnOffButton3_Current.BackgroundColor=[0.90,0.90,0.90];
                app.mAEditField_9.Enable=0;
                app.mAEditField_9Label.Enable=0;
                app.Lamp_18.Enable=0;
                app.Lamp_19.Enable=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.mainPanel = uipanel(app.UIFigure);
            app.Panel = uipanel(app.mainPanel);
            app.UpdateButton = uibutton(app.Panel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.ConnectButton = uibutton(app.Panel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp = uilamp(app.Panel);
            app.Panel_2 = uipanel(app.mainPanel);
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = 'Реле';
            app.NAMURButton = uibutton(app.Panel_2, 'push');
            app.NAMURButton.ButtonPushedFcn = createCallbackFcn(app, @NAMURButtonPushed, true);
            app.NAMURButton.Text = 'NAMUR';
            app.Button_2 = uibutton(app.Panel_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Токовый(4-20)';
            app.relayPanel = uipanel(app.UIFigure);
            app.relayPanel.Visible = 'off';
            app.Sensor1Panel = uipanel(app.relayPanel);
            app.Sensor1Panel.Title = 'Sensor 1';
            app.OnOffButton = uibutton(app.Sensor1Panel, 'state');
            app.OnOffButton.ValueChangedFcn = createCallbackFcn(app, @OnOffButtonValueChanged, true);
            app.OnOffButton.Text = 'On/Off';
            app.KnobLabel = uilabel(app.Sensor1Panel);
            app.KnobLabel.Visible = 'off';
            app.KnobLabel.Text = 'Питание';
            app.Knob = uiknob(app.Sensor1Panel, 'discrete');
            app.Knob.Items = {'Low', 'High'};
            app.Knob.ItemsData = {'1', '2'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Visible = 'off';
            app.Knob.Value = '1';
            app.Label_3 = uilabel(app.Sensor1Panel);
            app.Label_3.Text = 'Реле';
            app.Switch = uiswitch(app.Sensor1Panel, 'slider');
            app.Switch.Items = {'NC', 'NO'};
            app.Switch.Value = 'NO';
            app.Panel_4 = uipanel(app.Sensor1Panel);
            app.UIAxes = uiaxes(app.Panel_4);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.mAEditFieldLabel = uilabel(app.Panel_4);
            app.mAEditFieldLabel.Enable = 'off';
            app.mAEditFieldLabel.Text = {'Ток потребления mA'; ''};
            app.mAEditField = uieditfield(app.Panel_4, 'numeric');
            app.mAEditField.Editable = 'off';
            app.mAEditField.Enable = 'off';
            app.Label = uilabel(app.Panel_4);
            app.Label.Text = 'Залит';
            app.Lamp_2 = uilamp(app.Panel_4);
            app.Label_2 = uilabel(app.Panel_4);
            app.Label_2.Text = 'Неисправность';
            app.Lamp_3 = uilamp(app.Panel_4);
            app.Label_4 = uilabel(app.Sensor1Panel);
            app.Label_4.Text = 'Номер датчика';
            app.EditField = uieditfield(app.Sensor1Panel, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueDisplayFormat = '%.0f';
            app.Sensor2Panel = uipanel(app.relayPanel);
            app.Sensor2Panel.Title = 'Sensor 2';
            app.OnOffButton_2 = uibutton(app.Sensor2Panel, 'state');
            app.OnOffButton_2.ValueChangedFcn = createCallbackFcn(app, @OnOffButton_2ValueChanged, true);
            app.OnOffButton_2.Text = 'On/Off';
            app.Knob_2Label = uilabel(app.Sensor2Panel);
            app.Knob_2Label.Visible = 'off';
            app.Knob_2Label.Text = 'Питание';
            app.Knob_2 = uiknob(app.Sensor2Panel, 'discrete');
            app.Knob_2.Items = {'Low', 'High'};
            app.Knob_2.ItemsData = {'1', '2'};
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_2.Visible = 'off';
            app.Knob_2.Value = '1';
            app.Switch_2Label = uilabel(app.Sensor2Panel);
            app.Switch_2Label.Text = 'Реле';
            app.Switch_2 = uiswitch(app.Sensor2Panel, 'slider');
            app.Switch_2.Items = {'NC', 'NO'};
            app.Switch_2.Value = 'NO';
            app.Panel_5 = uipanel(app.Sensor2Panel);
            app.UIAxes_2 = uiaxes(app.Panel_5);
            title(app.UIAxes_2, {''; ''})
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.mAEditField_2Label = uilabel(app.Panel_5);
            app.mAEditField_2Label.Enable = 'off';
            app.mAEditField_2Label.Text = {'Ток потребления mA'; ''};
            app.mAEditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.mAEditField_2.Editable = 'off';
            app.mAEditField_2.Enable = 'off';
            app.Lamp_4Label = uilabel(app.Panel_5);
            app.Lamp_4Label.Text = 'Залит';
            app.Lamp_4 = uilamp(app.Panel_5);
            app.Lamp_5Label = uilabel(app.Panel_5);
            app.Lamp_5Label.Text = 'Неисправность';
            app.Lamp_5 = uilamp(app.Panel_5);
            app.EditField_2Label = uilabel(app.Sensor2Panel);
            app.EditField_2Label.Text = 'Номер датчика';
            app.EditField_2 = uieditfield(app.Sensor2Panel, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.Sensor3Panel = uipanel(app.relayPanel);
            app.Sensor3Panel.Title = 'Sensor 3';
            app.Panel_6 = uipanel(app.Sensor3Panel);
            app.UIAxes_3 = uiaxes(app.Panel_6);
            title(app.UIAxes_3, {''; ''})
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.mAEditField_3Label = uilabel(app.Panel_6);
            app.mAEditField_3Label.Enable = 'off';
            app.mAEditField_3Label.Text = {'Ток потребления mA'; ''};
            app.mAEditField_3 = uieditfield(app.Panel_6, 'numeric');
            app.mAEditField_3.Editable = 'off';
            app.mAEditField_3.Enable = 'off';
            app.Lamp_6Label = uilabel(app.Panel_6);
            app.Lamp_6Label.Text = 'Залит';
            app.Lamp_6 = uilamp(app.Panel_6);
            app.Lamp_7Label = uilabel(app.Panel_6);
            app.Lamp_7Label.Text = 'Неисправность';
            app.Lamp_7 = uilamp(app.Panel_6);
            app.OnOffButton_3 = uibutton(app.Sensor3Panel, 'state');
            app.OnOffButton_3.ValueChangedFcn = createCallbackFcn(app, @OnOffButton_3ValueChanged, true);
            app.OnOffButton_3.Text = 'On/Off';
            app.Knob_3Label = uilabel(app.Sensor3Panel);
            app.Knob_3Label.Visible = 'off';
            app.Knob_3Label.Text = 'Питание';
            app.Knob_3 = uiknob(app.Sensor3Panel, 'discrete');
            app.Knob_3.Items = {'Low', 'High'};
            app.Knob_3.ItemsData = {'1', '2'};
            app.Knob_3.ValueChangedFcn = createCallbackFcn(app, @Knob_3ValueChanged, true);
            app.Knob_3.Visible = 'off';
            app.Knob_3.Value = '1';
            app.Switch_3Label = uilabel(app.Sensor3Panel);
            app.Switch_3Label.Text = 'Реле';
            app.Switch_3 = uiswitch(app.Sensor3Panel, 'slider');
            app.Switch_3.Items = {'NC', 'NO'};
            app.Switch_3.Value = 'NO';
            app.EditField_3Label = uilabel(app.Sensor3Panel);
            app.EditField_3Label.Text = 'Номер датчика';
            app.EditField_3 = uieditfield(app.Sensor3Panel, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.ValueDisplayFormat = '%.0f';
            app.XButton_relay = uibutton(app.relayPanel, 'push');
            app.XButton_relay.ButtonPushedFcn = createCallbackFcn(app, @XButton_relayPushed, true);
            app.XButton_relay.Text = 'X';
            app.Button_4 = uibutton(app.relayPanel, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = 'Испытание';
            app.Button_3 = uibutton(app.relayPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'Отчет';
            app.RxIndicator = uilamp(app.relayPanel);
            app.NamurPanel = uipanel(app.UIFigure);
            app.NamurPanel.Visible = 'off';
            app.Sensor1Panel_2 = uipanel(app.NamurPanel);
            app.Sensor1Panel_2.Title = 'Sensor 1';
            app.OnOffButton1_Namur = uibutton(app.Sensor1Panel_2, 'state');
            app.OnOffButton1_Namur.ValueChangedFcn = createCallbackFcn(app, @OnOffButton1_NamurValueChanged, true);
            app.OnOffButton1_Namur.Text = 'On/Off';
            app.Knob_4Label = uilabel(app.Sensor1Panel_2);
            app.Knob_4Label.Visible = 'off';
            app.Knob_4Label.Text = 'Питание';
            app.Knob_4 = uiknob(app.Sensor1Panel_2, 'discrete');
            app.Knob_4.Items = {'Low', 'High'};
            app.Knob_4.ItemsData = {'1', '2'};
            app.Knob_4.ValueChangedFcn = createCallbackFcn(app, @Knob_4ValueChanged, true);
            app.Knob_4.Visible = 'off';
            app.Knob_4.Value = '1';
            app.Panel_7 = uipanel(app.Sensor1Panel_2);
            app.UIAxes_4 = uiaxes(app.Panel_7);
            title(app.UIAxes_4, {''; ''})
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.mAEditField_4Label = uilabel(app.Panel_7);
            app.mAEditField_4Label.Enable = 'off';
            app.mAEditField_4Label.Text = {'Ток потребления mA'; ''};
            app.mAEditField_4 = uieditfield(app.Panel_7, 'numeric');
            app.mAEditField_4.Editable = 'off';
            app.mAEditField_4.Enable = 'off';
            app.Lamp_8Label = uilabel(app.Panel_7);
            app.Lamp_8Label.Text = 'Залит';
            app.Lamp_8 = uilamp(app.Panel_7);
            app.Lamp_9Label = uilabel(app.Panel_7);
            app.Lamp_9Label.Text = 'Неисправность';
            app.Lamp_9 = uilamp(app.Panel_7);
            app.EditField_4Label = uilabel(app.Sensor1Panel_2);
            app.EditField_4Label.Text = 'Номер датчика';
            app.EditField_4 = uieditfield(app.Sensor1Panel_2, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.ValueDisplayFormat = '%.0f';
            app.XButton_Namur = uibutton(app.NamurPanel, 'push');
            app.XButton_Namur.ButtonPushedFcn = createCallbackFcn(app, @XButton_NamurPushed, true);
            app.XButton_Namur.Text = 'X';
            app.Test_Namur = uibutton(app.NamurPanel, 'state');
            app.Test_Namur.ValueChangedFcn = createCallbackFcn(app, @Test_NamurValueChanged, true);
            app.Test_Namur.Text = 'Испытание';
            app.Button_6 = uibutton(app.NamurPanel, 'push');
            app.Button_6.Text = 'Отчет';
            app.RxIndicator_2 = uilamp(app.NamurPanel);
            app.Sensor2Panel_2 = uipanel(app.NamurPanel);
            app.Sensor2Panel_2.Title = 'Sensor 2';
            app.OnOffButton2_Namur = uibutton(app.Sensor2Panel_2, 'state');
            app.OnOffButton2_Namur.ValueChangedFcn = createCallbackFcn(app, @OnOffButton2_NamurValueChanged, true);
            app.OnOffButton2_Namur.Text = 'On/Off';
            app.Knob_5Label = uilabel(app.Sensor2Panel_2);
            app.Knob_5Label.Visible = 'off';
            app.Knob_5Label.Text = 'Питание';
            app.Knob_5 = uiknob(app.Sensor2Panel_2, 'discrete');
            app.Knob_5.Items = {'Low', 'High'};
            app.Knob_5.ItemsData = {'1', '2'};
            app.Knob_5.ValueChangedFcn = createCallbackFcn(app, @Knob_5ValueChanged, true);
            app.Knob_5.Visible = 'off';
            app.Knob_5.Value = '1';
            app.Panel_8 = uipanel(app.Sensor2Panel_2);
            app.UIAxes_5 = uiaxes(app.Panel_8);
            title(app.UIAxes_5, {''; ''})
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.mAEditField_5Label = uilabel(app.Panel_8);
            app.mAEditField_5Label.Enable = 'off';
            app.mAEditField_5Label.Text = {'Ток потребления mA'; ''};
            app.mAEditField_5 = uieditfield(app.Panel_8, 'numeric');
            app.mAEditField_5.Editable = 'off';
            app.mAEditField_5.Enable = 'off';
            app.Lamp_10Label = uilabel(app.Panel_8);
            app.Lamp_10Label.Text = 'Залит';
            app.Lamp_10 = uilamp(app.Panel_8);
            app.Lamp_11Label = uilabel(app.Panel_8);
            app.Lamp_11Label.Text = 'Неисправность';
            app.Lamp_11 = uilamp(app.Panel_8);
            app.EditField_5Label = uilabel(app.Sensor2Panel_2);
            app.EditField_5Label.Text = 'Номер датчика';
            app.EditField_5 = uieditfield(app.Sensor2Panel_2, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.ValueDisplayFormat = '%.0f';
            app.Sensor3Panel_2 = uipanel(app.NamurPanel);
            app.Sensor3Panel_2.Title = 'Sensor 3';
            app.Panel_9 = uipanel(app.Sensor3Panel_2);
            app.UIAxes_6 = uiaxes(app.Panel_9);
            title(app.UIAxes_6, {''; ''})
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            app.mAEditField_6Label = uilabel(app.Panel_9);
            app.mAEditField_6Label.Enable = 'off';
            app.mAEditField_6Label.Text = {'Ток потребления mA'; ''};
            app.mAEditField_6 = uieditfield(app.Panel_9, 'numeric');
            app.mAEditField_6.Editable = 'off';
            app.mAEditField_6.Enable = 'off';
            app.Lamp_12Label = uilabel(app.Panel_9);
            app.Lamp_12Label.Text = 'Залит';
            app.Lamp_12 = uilamp(app.Panel_9);
            app.Lamp_13Label = uilabel(app.Panel_9);
            app.Lamp_13Label.Text = 'Неисправность';
            app.Lamp_13 = uilamp(app.Panel_9);
            app.OnOffButton3_Namur = uibutton(app.Sensor3Panel_2, 'state');
            app.OnOffButton3_Namur.ValueChangedFcn = createCallbackFcn(app, @OnOffButton3_NamurValueChanged, true);
            app.OnOffButton3_Namur.Text = 'On/Off';
            app.Knob_6Label = uilabel(app.Sensor3Panel_2);
            app.Knob_6Label.Visible = 'off';
            app.Knob_6Label.Text = 'Питание';
            app.Knob_6 = uiknob(app.Sensor3Panel_2, 'discrete');
            app.Knob_6.Items = {'Low', 'High'};
            app.Knob_6.ItemsData = {'1', '2'};
            app.Knob_6.ValueChangedFcn = createCallbackFcn(app, @Knob_6ValueChanged, true);
            app.Knob_6.Visible = 'off';
            app.Knob_6.Value = '1';
            app.EditField_6Label = uilabel(app.Sensor3Panel_2);
            app.EditField_6Label.Text = 'Номер датчика';
            app.EditField_6 = uieditfield(app.Sensor3Panel_2, 'numeric');
            app.EditField_6.Limits = [0 Inf];
            app.EditField_6.ValueDisplayFormat = '%.0f';
            app.CurrentPanel = uipanel(app.UIFigure);
            app.XButton_Namur_2 = uibutton(app.CurrentPanel, 'push');
            app.XButton_Namur_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_Namur_2Pushed, true);
            app.XButton_Namur_2.Text = 'X';
            app.Test_Current = uibutton(app.CurrentPanel, 'state');
            app.Test_Current.Text = 'Испытание';
            app.Button_7 = uibutton(app.CurrentPanel, 'push');
            app.Button_7.Text = 'Отчет';
            app.RxIndicator_3 = uilamp(app.CurrentPanel);
            app.Sensor1Panel_3 = uipanel(app.CurrentPanel);
            app.Sensor1Panel_3.Title = 'Sensor 1';
            app.OnOffButton1_Current = uibutton(app.Sensor1Panel_3, 'state');
            app.OnOffButton1_Current.ValueChangedFcn = createCallbackFcn(app, @OnOffButton1_CurrentValueChanged, true);
            app.OnOffButton1_Current.Text = 'On/Off';
            app.Knob_7Label = uilabel(app.Sensor1Panel_3);
            app.Knob_7Label.Visible = 'off';
            app.Knob_7Label.Text = 'Питание';
            app.Knob_7 = uiknob(app.Sensor1Panel_3, 'discrete');
            app.Knob_7.Items = {'Low', 'High'};
            app.Knob_7.ItemsData = {'1', '2'};
            app.Knob_7.Visible = 'off';
            app.Knob_7.Value = '1';
            app.Panel_10 = uipanel(app.Sensor1Panel_3);
            app.UIAxes_7 = uiaxes(app.Panel_10);
            title(app.UIAxes_7, {''; ''})
            xlabel(app.UIAxes_7, '')
            ylabel(app.UIAxes_7, '')
            app.mAEditField_7Label = uilabel(app.Panel_10);
            app.mAEditField_7Label.Enable = 'off';
            app.mAEditField_7Label.Text = {'Ток потребления mA'; ''};
            app.mAEditField_7 = uieditfield(app.Panel_10, 'numeric');
            app.mAEditField_7.Editable = 'off';
            app.mAEditField_7.Enable = 'off';
            app.Lamp_14Label = uilabel(app.Panel_10);
            app.Lamp_14Label.Text = 'Залит';
            app.Lamp_14 = uilamp(app.Panel_10);
            app.Lamp_15Label = uilabel(app.Panel_10);
            app.Lamp_15Label.Text = 'Неисправность';
            app.Lamp_15 = uilamp(app.Panel_10);
            app.EditField_7Label = uilabel(app.Sensor1Panel_3);
            app.EditField_7Label.Text = 'Номер датчика';
            app.EditField_7 = uieditfield(app.Sensor1Panel_3, 'numeric');
            app.EditField_7.Limits = [0 Inf];
            app.EditField_7.ValueDisplayFormat = '%.0f';
            app.Sensor2Panel_3 = uipanel(app.CurrentPanel);
            app.Sensor2Panel_3.Title = 'Sensor 2';
            app.OnOffButton2_Current = uibutton(app.Sensor2Panel_3, 'state');
            app.OnOffButton2_Current.ValueChangedFcn = createCallbackFcn(app, @OnOffButton2_CurrentValueChanged, true);
            app.OnOffButton2_Current.Text = 'On/Off';
            app.Knob_8Label = uilabel(app.Sensor2Panel_3);
            app.Knob_8Label.Visible = 'off';
            app.Knob_8Label.Text = 'Питание';
            app.Knob_8 = uiknob(app.Sensor2Panel_3, 'discrete');
            app.Knob_8.Items = {'Low', 'High'};
            app.Knob_8.ItemsData = {'1', '2'};
            app.Knob_8.Visible = 'off';
            app.Knob_8.Value = '1';
            app.Panel_11 = uipanel(app.Sensor2Panel_3);
            app.UIAxes_8 = uiaxes(app.Panel_11);
            title(app.UIAxes_8, {''; ''})
            xlabel(app.UIAxes_8, '')
            ylabel(app.UIAxes_8, '')
            app.mAEditField_8Label = uilabel(app.Panel_11);
            app.mAEditField_8Label.Enable = 'off';
            app.mAEditField_8Label.Text = {'Ток потребления mA'; ''};
            app.mAEditField_8 = uieditfield(app.Panel_11, 'numeric');
            app.mAEditField_8.Editable = 'off';
            app.mAEditField_8.Enable = 'off';
            app.Lamp_16Label = uilabel(app.Panel_11);
            app.Lamp_16Label.Text = 'Залит';
            app.Lamp_16 = uilamp(app.Panel_11);
            app.Lamp_17Label = uilabel(app.Panel_11);
            app.Lamp_17Label.Text = 'Неисправность';
            app.Lamp_17 = uilamp(app.Panel_11);
            app.EditField_8Label = uilabel(app.Sensor2Panel_3);
            app.EditField_8Label.Text = 'Номер датчика';
            app.EditField_8 = uieditfield(app.Sensor2Panel_3, 'numeric');
            app.EditField_8.Limits = [0 Inf];
            app.EditField_8.ValueDisplayFormat = '%.0f';
            app.Sensor3Panel_3 = uipanel(app.CurrentPanel);
            app.Sensor3Panel_3.Title = 'Sensor 3';
            app.Panel_12 = uipanel(app.Sensor3Panel_3);
            app.UIAxes_9 = uiaxes(app.Panel_12);
            title(app.UIAxes_9, {''; ''})
            xlabel(app.UIAxes_9, '')
            ylabel(app.UIAxes_9, '')
            app.mAEditField_9Label = uilabel(app.Panel_12);
            app.mAEditField_9Label.Enable = 'off';
            app.mAEditField_9Label.Text = {'Ток потребления mA'; ''};
            app.mAEditField_9 = uieditfield(app.Panel_12, 'numeric');
            app.mAEditField_9.Editable = 'off';
            app.mAEditField_9.Enable = 'off';
            app.Lamp_18Label = uilabel(app.Panel_12);
            app.Lamp_18Label.Text = 'Залит';
            app.Lamp_18 = uilamp(app.Panel_12);
            app.Lamp_19Label = uilabel(app.Panel_12);
            app.Lamp_19Label.Text = 'Неисправность';
            app.Lamp_19 = uilamp(app.Panel_12);
            app.OnOffButton3_Current = uibutton(app.Sensor3Panel_3, 'state');
            app.OnOffButton3_Current.ValueChangedFcn = createCallbackFcn(app, @OnOffButton3_CurrentValueChanged, true);
            app.OnOffButton3_Current.Text = 'On/Off';
            app.Knob_9Label = uilabel(app.Sensor3Panel_3);
            app.Knob_9Label.Visible = 'off';
            app.Knob_9Label.Text = 'Питание';
            app.Knob_9 = uiknob(app.Sensor3Panel_3, 'discrete');
            app.Knob_9.Items = {'Low', 'High'};
            app.Knob_9.ItemsData = {'1', '2'};
            app.Knob_9.Visible = 'off';
            app.Knob_9.Value = '1';
            app.EditField_9Label = uilabel(app.Sensor3Panel_3);
            app.EditField_9Label.Text = 'Номер датчика';
            app.EditField_9 = uieditfield(app.Sensor3Panel_3, 'numeric');
            app.EditField_9.Limits = [0 Inf];
            app.EditField_9.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisurStend

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end