classdef VisurStend01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel_7             matlab.ui.container.Panel
        Panel               matlab.ui.container.Panel
        UpdateButton        matlab.ui.control.Button
        DropDown            matlab.ui.control.DropDown
        ConButton           matlab.ui.control.Button
        Lamp                matlab.ui.control.Lamp
        Panel_2             matlab.ui.container.Panel
        Button              matlab.ui.control.Button
        NAMURButton         matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Panel_3             matlab.ui.container.Panel
        Sensor1Panel        matlab.ui.container.Panel
        OnOffButton         matlab.ui.control.StateButton
        KnobLabel           matlab.ui.control.Label
        Knob                matlab.ui.control.DiscreteKnob
        Label_3             matlab.ui.control.Label
        Switch              matlab.ui.control.Switch
        Panel_4             matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
        mAEditFieldLabel    matlab.ui.control.Label
        mAEditField         matlab.ui.control.NumericEditField
        Label               matlab.ui.control.Label
        Lamp_2              matlab.ui.control.Lamp
        Label_2             matlab.ui.control.Label
        Lamp_3              matlab.ui.control.Lamp
        Sensor2Panel        matlab.ui.container.Panel
        OnOffButton_2       matlab.ui.control.StateButton
        Knob_2Label         matlab.ui.control.Label
        Knob_2              matlab.ui.control.DiscreteKnob
        Switch_2Label       matlab.ui.control.Label
        Switch_2            matlab.ui.control.Switch
        Panel_5             matlab.ui.container.Panel
        UIAxes_2            matlab.ui.control.UIAxes
        mAEditField_2Label  matlab.ui.control.Label
        mAEditField_2       matlab.ui.control.NumericEditField
        Lamp_4Label         matlab.ui.control.Label
        Lamp_4              matlab.ui.control.Lamp
        Lamp_5Label         matlab.ui.control.Label
        Lamp_5              matlab.ui.control.Lamp
        Sensor3Panel        matlab.ui.container.Panel
        Panel_6             matlab.ui.container.Panel
        UIAxes_3            matlab.ui.control.UIAxes
        mAEditField_3Label  matlab.ui.control.Label
        mAEditField_3       matlab.ui.control.NumericEditField
        Lamp_6Label         matlab.ui.control.Label
        Lamp_6              matlab.ui.control.Lamp
        Lamp_7Label         matlab.ui.control.Label
        Lamp_7              matlab.ui.control.Lamp
        OnOffButton_3       matlab.ui.control.StateButton
        Knob_3Label         matlab.ui.control.Label
        Knob_3              matlab.ui.control.DiscreteKnob
        Switch_3Label       matlab.ui.control.Label
        Switch_3            matlab.ui.control.Switch
        XButton             matlab.ui.control.Button
        StarttestButton     matlab.ui.control.StateButton
        EditFieldLabel      matlab.ui.control.Label
        EditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        WorkComPort=0; 
        MyTimer % Description
    end

    
    methods (Access = private)
        
        function MyTimerFcn(app,~,~)
            
            a=[0xAA,0x01,0x02,0x03,0x04];
            s = serialport(app.WorkComPort,9600);
            %s = serialport("COM5",9600);
            write(s,a,"uint8");
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %Preview
            app.Lamp_2.Enable=0;
            app.Lamp_3.Enable=0;
            app.Lamp_4.Enable=0;
            app.Lamp_5.Enable=0;
            app.Lamp_6.Enable=0;
            app.Lamp_7.Enable=0;
            
            %Free COM port's
            app.DropDown.Items = serialportlist("available");
            
             % Create timer object
             
            app.MyTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MyTimerFcn);        % Specify callback function


        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            app.DropDown.Items = serialportlist("available");


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            if app.ConButton.Text == 'Dis'
               app.ConButton.Text = 'Con';
               app.Lamp.Color=[0.80,0.80,0.80];
            end
            
        end

        % Button pushed function: ConButton
        function ConButtonPushed(app, event)
            
            if  app.ConButton.Text == 'Con'
            s = app.DropDown.Value;
            app.WorkComPort=s;
            serialport(s,9600);
            app.ConButton.Text = 'Dis';
            app.Lamp.Color=[0.39,0.83,0.07];
            else 
            app.ConButton.Text = 'Con';
            app.Lamp.Color=[0.80,0.80,0.80];
            end
            
             
        end

        % Callback function
        function ButtonPushed(app, event)
            fclose("all");
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
         app.Panel_3.Visible=1; 
         app.Panel_7.Visible=0;
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            
            
            app.StarttestButton.Value=0;
            app.StarttestButton.BackgroundColor=[0.90,0.90,0.90];
            stop(app.MyTimer);
            app.Panel_3.Visible=0;
            app.Panel_7.Visible=1;
        end

        % Value changed function: OnOffButton
        function OnOffButtonValueChanged(app, event)
            value = app.OnOffButton.Value;
            
            
            %Power tumbler
            
            if (app.OnOffButton.Value==1) 
               app.Knob.Visible=1;
               app.KnobLabel.Visible=1;
               app.Knob_2.Visible=0;
               app.Knob_2Label.Visible=0;
               app.Knob_3.Visible=0;
               app.Knob_3Label.Visible=0;
            elseif (app.OnOffButton.Value==0 && app.OnOffButton_2.Value==1)
               app.Knob.Visible=0;
               app.KnobLabel.Visible=0;
               app.Knob_2.Visible=1;
               app.Knob_2Label.Visible=1;
               app.Knob_3.Visible=0;
               app.Knob_3Label.Visible=0;
               elseif (app.OnOffButton_2.Value==0 && app.OnOffButton_3.Value==1 && app.OnOffButton.Value==0)
               app.Knob.Visible=0;
               app.KnobLabel.Visible=0;
               app.Knob_2.Visible=0;
               app.Knob_2Label.Visible=0;
               app.Knob_3.Visible=1;
               app.Knob_3Label.Visible=1;
            else
               app.Knob.Visible=0;
               app.KnobLabel.Visible=0;
            end


            if value==1
                app.OnOffButton.BackgroundColor=[0.42,0.69,0.66];
                app.mAEditField.Enable=1;
                app.mAEditFieldLabel.Enable=1;
                app.Lamp_2.Enable=1;
                app.Lamp_3.Enable=1;
            else
                app.OnOffButton.BackgroundColor=[0.90,0.90,0.90];
                app.mAEditField.Enable=0;
                app.mAEditFieldLabel.Enable=0;
                app.Lamp_2.Enable=0;
                app.Lamp_3.Enable=0;
            end
        end

        % Value changed function: OnOffButton_2
        function OnOffButton_2ValueChanged(app, event)
            value = app.OnOffButton_2.Value;
            
            %Power tumbler
            
            if (app.OnOffButton.Value==0 && app.OnOffButton_2.Value==1) 
               app.Knob_2.Visible=1;
               app.Knob_2Label.Visible=1;
               app.Knob_3.Visible=0;
               app.Knob_3Label.Visible=0;
            elseif (app.OnOffButton_2.Value==0 && app.OnOffButton_3.Value==1 && app.OnOffButton.Value==0)
               app.Knob_2.Visible=0;
               app.Knob_2Label.Visible=0;
               app.Knob_3.Visible=1;
               app.Knob_3Label.Visible=1;
               
            else
               app.Knob_2.Visible=0;
               app.Knob_2Label.Visible=0;
            end
            

           %Output data vision 
            if value==1
                app.OnOffButton_2.BackgroundColor=[0.42,0.69,0.66];
                app.mAEditField_2.Enable=1;
                app.mAEditField_2Label.Enable=1;
                app.Lamp_4.Enable=1;
                app.Lamp_5.Enable=1;
            else
                app.OnOffButton_2.BackgroundColor=[0.90,0.90,0.90];
                app.mAEditField_2.Enable=0;
                app.mAEditField_2Label.Enable=0;
                app.Lamp_4.Enable=0;
                app.Lamp_5.Enable=0;
            end
        end

        % Value changed function: OnOffButton_3
        function OnOffButton_3ValueChanged(app, event)
            value = app.OnOffButton_3.Value;
            
             %Power tumbler
            
            if (app.OnOffButton.Value==0 && app.OnOffButton_2.Value==0 && app.OnOffButton_3.Value==1) 
               app.Knob_3.Visible=1;
               app.Knob_3Label.Visible=1;
            else
               app.Knob_3.Visible=0;
               app.Knob_3Label.Visible=0;
            end


            if value==1
                app.OnOffButton_3.BackgroundColor=[0.42,0.69,0.66];
                app.mAEditField_3.Enable=1;
                app.mAEditField_3Label.Enable=1;
                app.Lamp_6.Enable=1;
                app.Lamp_7.Enable=1;
            else
                app.OnOffButton_3.BackgroundColor=[0.90,0.90,0.90];
                app.mAEditField_3.Enable=0;
                app.mAEditField_3Label.Enable=0;
                app.Lamp_6.Enable=0;
                app.Lamp_7.Enable=0;
            end
        end

        % Value changed function: StarttestButton
        function StarttestButtonValueChanged(app, event)
        
            if strcmp(app.MyTimer.Running, 'off')
               start(app.MyTimer);
               app.StarttestButton.BackgroundColor=[0.42,0.69,0.66];

            else 
               stop(app.MyTimer);
               app.StarttestButton.BackgroundColor=[0.90,0.90,0.90];    
            end


          % data = read(s,5,"uint8");
          %  data1=data(1);
          % app.EditField.Value=data1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.MyTimer);
            delete(app.MyTimer);
            delete(app);
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            app.Knob_2.Value=value;
            app.Knob_3.Value=value;
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
            value = app.Knob_2.Value;
            app.Knob.Value=value;
            app.Knob_3.Value=value;
        end

        % Value changed function: Knob_3
        function Knob_3ValueChanged(app, event)
            value = app.Knob_3.Value;
            app.Knob.Value=value;
            app.Knob_2.Value=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_7 = uipanel(app.UIFigure);
            app.Panel = uipanel(app.Panel_7);
            app.UpdateButton = uibutton(app.Panel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.ConButton = uibutton(app.Panel, 'push');
            app.ConButton.ButtonPushedFcn = createCallbackFcn(app, @ConButtonPushed, true);
            app.ConButton.Text = 'Con';
            app.Lamp = uilamp(app.Panel);
            app.Panel_2 = uipanel(app.Panel_7);
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = 'Реле';
            app.NAMURButton = uibutton(app.Panel_2, 'push');
            app.NAMURButton.Text = 'NAMUR';
            app.Button_2 = uibutton(app.Panel_2, 'push');
            app.Button_2.Text = 'Токовый(4-20)';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Visible = 'off';
            app.Sensor1Panel = uipanel(app.Panel_3);
            app.Sensor1Panel.Title = 'Sensor 1';
            app.OnOffButton = uibutton(app.Sensor1Panel, 'state');
            app.OnOffButton.ValueChangedFcn = createCallbackFcn(app, @OnOffButtonValueChanged, true);
            app.OnOffButton.Text = 'On/Off';
            app.KnobLabel = uilabel(app.Sensor1Panel);
            app.KnobLabel.Visible = 'off';
            app.KnobLabel.Text = 'Питание';
            app.Knob = uiknob(app.Sensor1Panel, 'discrete');
            app.Knob.Items = {'Low', 'High'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Visible = 'off';
            app.Knob.Value = 'Low';
            app.Label_3 = uilabel(app.Sensor1Panel);
            app.Label_3.Text = 'Реле';
            app.Switch = uiswitch(app.Sensor1Panel, 'slider');
            app.Switch.Items = {'NC', 'NO'};
            app.Switch.Value = 'NO';
            app.Panel_4 = uipanel(app.Sensor1Panel);
            app.UIAxes = uiaxes(app.Panel_4);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.mAEditFieldLabel = uilabel(app.Panel_4);
            app.mAEditFieldLabel.Enable = 'off';
            app.mAEditFieldLabel.Text = {'Ток потребления mA'; ''};
            app.mAEditField = uieditfield(app.Panel_4, 'numeric');
            app.mAEditField.Editable = 'off';
            app.mAEditField.Enable = 'off';
            app.Label = uilabel(app.Panel_4);
            app.Label.Text = 'Залит';
            app.Lamp_2 = uilamp(app.Panel_4);
            app.Label_2 = uilabel(app.Panel_4);
            app.Label_2.Text = 'Неисправность';
            app.Lamp_3 = uilamp(app.Panel_4);
            app.Sensor2Panel = uipanel(app.Panel_3);
            app.Sensor2Panel.Title = 'Sensor 2';
            app.OnOffButton_2 = uibutton(app.Sensor2Panel, 'state');
            app.OnOffButton_2.ValueChangedFcn = createCallbackFcn(app, @OnOffButton_2ValueChanged, true);
            app.OnOffButton_2.Text = 'On/Off';
            app.Knob_2Label = uilabel(app.Sensor2Panel);
            app.Knob_2Label.Visible = 'off';
            app.Knob_2Label.Text = 'Питание';
            app.Knob_2 = uiknob(app.Sensor2Panel, 'discrete');
            app.Knob_2.Items = {'Low', 'High'};
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_2.Visible = 'off';
            app.Knob_2.Value = 'Low';
            app.Switch_2Label = uilabel(app.Sensor2Panel);
            app.Switch_2Label.Text = 'Реле';
            app.Switch_2 = uiswitch(app.Sensor2Panel, 'slider');
            app.Switch_2.Items = {'NC', 'NO'};
            app.Switch_2.Value = 'NO';
            app.Panel_5 = uipanel(app.Sensor2Panel);
            app.UIAxes_2 = uiaxes(app.Panel_5);
            title(app.UIAxes_2, {''; ''})
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.mAEditField_2Label = uilabel(app.Panel_5);
            app.mAEditField_2Label.Enable = 'off';
            app.mAEditField_2Label.Text = {'Ток потребления mA'; ''};
            app.mAEditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.mAEditField_2.Editable = 'off';
            app.mAEditField_2.Enable = 'off';
            app.Lamp_4Label = uilabel(app.Panel_5);
            app.Lamp_4Label.Text = 'Залит';
            app.Lamp_4 = uilamp(app.Panel_5);
            app.Lamp_5Label = uilabel(app.Panel_5);
            app.Lamp_5Label.Text = 'Неисправность';
            app.Lamp_5 = uilamp(app.Panel_5);
            app.Sensor3Panel = uipanel(app.Panel_3);
            app.Sensor3Panel.Title = 'Sensor 3';
            app.Panel_6 = uipanel(app.Sensor3Panel);
            app.UIAxes_3 = uiaxes(app.Panel_6);
            title(app.UIAxes_3, {''; ''})
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.mAEditField_3Label = uilabel(app.Panel_6);
            app.mAEditField_3Label.Enable = 'off';
            app.mAEditField_3Label.Text = {'Ток потребления mA'; ''};
            app.mAEditField_3 = uieditfield(app.Panel_6, 'numeric');
            app.mAEditField_3.Editable = 'off';
            app.mAEditField_3.Enable = 'off';
            app.Lamp_6Label = uilabel(app.Panel_6);
            app.Lamp_6Label.Text = 'Залит';
            app.Lamp_6 = uilamp(app.Panel_6);
            app.Lamp_7Label = uilabel(app.Panel_6);
            app.Lamp_7Label.Text = 'Неисправность';
            app.Lamp_7 = uilamp(app.Panel_6);
            app.OnOffButton_3 = uibutton(app.Sensor3Panel, 'state');
            app.OnOffButton_3.ValueChangedFcn = createCallbackFcn(app, @OnOffButton_3ValueChanged, true);
            app.OnOffButton_3.Text = 'On/Off';
            app.Knob_3Label = uilabel(app.Sensor3Panel);
            app.Knob_3Label.Visible = 'off';
            app.Knob_3Label.Text = 'Питание';
            app.Knob_3 = uiknob(app.Sensor3Panel, 'discrete');
            app.Knob_3.Items = {'Low', 'High'};
            app.Knob_3.ValueChangedFcn = createCallbackFcn(app, @Knob_3ValueChanged, true);
            app.Knob_3.Visible = 'off';
            app.Knob_3.Value = 'Low';
            app.Switch_3Label = uilabel(app.Sensor3Panel);
            app.Switch_3Label.Text = 'Реле';
            app.Switch_3 = uiswitch(app.Sensor3Panel, 'slider');
            app.Switch_3.Items = {'NC', 'NO'};
            app.Switch_3.Value = 'NO';
            app.XButton = uibutton(app.Panel_3, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.StarttestButton = uibutton(app.Panel_3, 'state');
            app.StarttestButton.ValueChangedFcn = createCallbackFcn(app, @StarttestButtonValueChanged, true);
            app.StarttestButton.Text = 'Start test';
            app.EditFieldLabel = uilabel(app.Panel_3);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Panel_3, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisurStend01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end